classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ImportAudioFileButton         matlab.ui.control.Button
        PlayButton                    matlab.ui.control.Button
        PauseButton                   matlab.ui.control.Button
        InputOptionsDropDownLabel     matlab.ui.control.Label
        InputOptionsDropDown          matlab.ui.control.DropDown
        InputLabel                    matlab.ui.control.Label
        OutputLabel                   matlab.ui.control.Label
        OutputPlayButton              matlab.ui.control.Button
        OutputPauseButton             matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        AmplitudeEditFieldLabel       matlab.ui.control.Label
        AmplitudeEditField            matlab.ui.control.NumericEditField
        PhaseEditFieldLabel           matlab.ui.control.Label
        PhaseEditField                matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel       matlab.ui.control.Label
        FrequencyEditField            matlab.ui.control.NumericEditField
        SampleFreq1000EditFieldLabel  matlab.ui.control.Label
        SampleFreq1000EditField       matlab.ui.control.NumericEditField
        DurationsEditFieldLabel       matlab.ui.control.Label
        DurationsEditField            matlab.ui.control.NumericEditField
        GenerateButton                matlab.ui.control.Button
        Image_2                       matlab.ui.control.Image
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes4                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportAudioFileButton
        function ImportAudioFileButtonPushed(app, event)
            global y;
            global fs;
            [filename, filepath] = uigetfile({'*.wav'; '*.mp3'; '*.*'}, 'Pick an audio file');
            if ~ischar(filename); return; end
            try
                fullname = fullfile(filepath, filename);
                [y, fs] = audioread(fullname);
            catch ME
                uiwait(msgbox('Could not open that file with audioread'));
            end
            plot(app.UIAxes2,y)
            data_fft = fft(y);
            plot(app.UIAxes,abs(data_fft(:,1)));
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
           global Player;
           global y;
           global fs;
            try
                Player = audioplayer(y,fs);
                play(Player);
            catch ME
                uiwait(msgbox('Could not play the input!'));
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            global Player;
            stop(Player);
        end

        % Value changed function: InputOptionsDropDown
        function InputOptionsDropDownValueChanged(app, event)
            value = app.InputOptionsDropDown.Value;
            global inputValue;
            switch value
                case 'Import File'
                    app.ImportAudioFileButton.Visible = 'on';
                    app.GenerateButton.Visible = 'off';
                    app.DurationsEditField.Visible = 'off';
                    app.SampleFreq1000EditField.Visible = 'off';
                    app.FrequencyEditField.Visible = 'off';
                    app.PhaseEditField.Visible = 'off';
                    app.AmplitudeEditField.Visible = 'off';
                    app.DurationsEditFieldLabel.Visible = 'off';
                    app.SampleFreq1000EditFieldLabel.Visible = 'off';
                    app.FrequencyEditFieldLabel.Visible = 'off';
                    app.PhaseEditFieldLabel.Visible = 'off';
                    app.AmplitudeEditFieldLabel.Visible = 'off';
                case 'Sinusoidal'
                    app.ImportAudioFileButton.Visible = 'off';
                    app.GenerateButton.Visible = 'on';
                    app.DurationsEditField.Visible = 'on';
                    app.SampleFreq1000EditField.Visible = 'on';
                    app.FrequencyEditField.Visible = 'on';
                    app.PhaseEditField.Visible = 'on';
                    app.AmplitudeEditField.Visible = 'on';
                    app.DurationsEditFieldLabel.Visible = 'on';
                    app.SampleFreq1000EditFieldLabel.Visible = 'on';
                    app.FrequencyEditFieldLabel.Visible = 'on';
                    app.PhaseEditFieldLabel.Visible = 'on';
                    app.AmplitudeEditFieldLabel.Visible = 'on';
                    inputValue = 1;
                case 'Pulse'
                    app.ImportAudioFileButton.Visible = 'off';
                    app.GenerateButton.Visible = 'on';
                    app.DurationsEditField.Visible = 'on';
                    app.SampleFreq1000EditField.Visible = 'on';
                    app.FrequencyEditField.Visible = 'on';
                    app.PhaseEditField.Visible = 'on';
                    app.AmplitudeEditField.Visible = 'on';
                    app.DurationsEditFieldLabel.Visible = 'on';
                    app.SampleFreq1000EditFieldLabel.Visible = 'on';
                    app.FrequencyEditFieldLabel.Visible = 'on';
                    app.PhaseEditFieldLabel.Visible = 'on';
                    app.AmplitudeEditFieldLabel.Visible = 'on';
                    inputValue = 2;
                 
            end
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            global input;
            global inputValue;
            global y;
            global fs;
            if inputValue == 1
                input = sine_generator(app.AmplitudeEditField.Value,app.PhaseEditField.Value,app.FrequencyEditField.Value,app.SampleFreq1000EditField.Value,app.DurationsEditField.Value);
                y = input;
                fs = app.SampleFreq1000EditField.Value;
            elseif inputValue == 2
                input = pulse_generator(app.AmplitudeEditField.Value,app.PhaseEditField.Value,app.FrequencyEditField.Value,app.SampleFreq1000EditField.Value,app.DurationsEditField.Value);
                y = input;
                fs = app.SampleFreq1000EditField.Value;
            end
            plot(app.UIAxes2,input)
            plot(app.UIAxes,abs(fft(input)))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImportAudioFileButton = uibutton(app.UIFigure, 'push');
            app.ImportAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportAudioFileButtonPushed, true);
            app.ImportAudioFileButton.Visible = 'off';
            app.ImportAudioFileButton.Text = {'Import Audio File'; ''};
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Stop';
            app.InputOptionsDropDownLabel = uilabel(app.UIFigure);
            app.InputOptionsDropDownLabel.Text = 'Input Options';
            app.InputOptionsDropDown = uidropdown(app.UIFigure);
            app.InputOptionsDropDown.Items = {'Sinusoidal', ' ', 'Import File', 'Pulse'};
            app.InputOptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @InputOptionsDropDownValueChanged, true);
            app.InputOptionsDropDown.Value = ' ';
            app.InputLabel = uilabel(app.UIFigure);
            app.InputLabel.Text = 'Input';
            app.OutputLabel = uilabel(app.UIFigure);
            app.OutputLabel.Text = 'Output';
            app.OutputPlayButton = uibutton(app.UIFigure, 'push');
            app.OutputPlayButton.Text = 'Play';
            app.OutputPauseButton = uibutton(app.UIFigure, 'push');
            app.OutputPauseButton.Text = 'Stop';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'DSCF2697.jpg';
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Visible = 'off';
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeEditField.Visible = 'off';
            app.PhaseEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseEditFieldLabel.Visible = 'off';
            app.PhaseEditFieldLabel.Text = 'Phase ';
            app.PhaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseEditField.Visible = 'off';
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Visible = 'off';
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.Visible = 'off';
            app.SampleFreq1000EditFieldLabel = uilabel(app.UIFigure);
            app.SampleFreq1000EditFieldLabel.Visible = 'off';
            app.SampleFreq1000EditFieldLabel.Text = 'Sample Freq. (>1000)';
            app.SampleFreq1000EditField = uieditfield(app.UIFigure, 'numeric');
            app.SampleFreq1000EditField.Visible = 'off';
            app.DurationsEditFieldLabel = uilabel(app.UIFigure);
            app.DurationsEditFieldLabel.Visible = 'off';
            app.DurationsEditFieldLabel.Text = 'Duration (s)';
            app.DurationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationsEditField.Visible = 'off';
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Visible = 'off';
            app.GenerateButton.Text = 'Generate!';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = 'DSCF2697.jpg';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'FFT of Input')
            xlabel(app.UIAxes, 'Frequency')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Graph of Input')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'FFT of Output')
            xlabel(app.UIAxes3, 'Frequency')
            ylabel(app.UIAxes3, 'Amplitude')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Graph of Output')
            xlabel(app.UIAxes4, 'Time')
            ylabel(app.UIAxes4, 'Amplitude')
            zlabel(app.UIAxes4, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end